classdef prelch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ProbesTab                       matlab.ui.container.Tab
        DEFAULTPROBELabel               matlab.ui.control.Label
        RetrieveButton                  matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        SAVEBtnProbe                    matlab.ui.control.Button
        NumberofprobesEditField         matlab.ui.control.NumericEditField
        NumberofprobesEditFieldLabel    matlab.ui.control.Label
        probeGuid                       matlab.ui.control.EditField
        probeUIDauniqueidentifierLabel  matlab.ui.control.Label
        ProbesUITable                   matlab.ui.control.Table
        ElectrodesTab                   matlab.ui.container.Tab
        DEFAULTSLabel                   matlab.ui.control.Label
        SAVEBtnElectrode                matlab.ui.control.Button
        NumberofelectrodesEditField     matlab.ui.control.NumericEditField
        NumberofelectrodesEditFieldLabel  matlab.ui.control.Label
        ElectrodesUITable               matlab.ui.control.Table
        ChannelsTab                     matlab.ui.container.Tab
        DEFAULTSLabel_2                 matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        ChannelsUITable                 matlab.ui.control.Table
        SaveBtnChannel                  matlab.ui.control.Button
        NumberofchannelsEF              matlab.ui.control.NumericEditField
        NumberofchannelsEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SubjectDropDown                 matlab.ui.control.DropDown
        SubjectDropDownLabel            matlab.ui.control.Label
        SetupDropDown                   matlab.ui.control.DropDown
        SetupDropDownLabel              matlab.ui.control.Label
        LabnameLabel                    matlab.ui.control.Label
        ProjectDropDown                 matlab.ui.control.DropDown
        ProjectDropDownLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        lab_name % Description
        ProjectIds % Description
        Setups % Description
        SubjectIds % Description

        
        probe_flds
        electrode_flds % Description
        channel_flds % Description

        probe_defaults %The actual defaults used to fill the probes
        probe_array

        electrode_defaults
        electrode_array

        channel_defaults % Description
        channel_array

        ephys % ephys metadata for selected setup
        
    end
    
    methods (Access = private)
        
        function defaults = fill_defaults(~, field_types)
            defaults = cell(numel(field_types),1);
            for i = 1:numel(field_types)
                if contains(field_types{i}, 'varchar')
                    defaults{i} = '';
                else 
                    defaults{i} = 0;
                end
            end
        end
        
        function create_probe_struct(app, num)
            
            probe = struct();
            probe.(app.probe_flds{1}) = append(app.probeGuid.Value, ['_' num2str(num, '%03d')]);
            probe.(app.probe_flds{2}) = app.SubjectDropDown.Value;
            for i = 3:numel(app.probe_flds)
                probe.(app.probe_flds{i}) = app.probe_defaults{i};
            end
            
           if isempty(app.probe_array)
            app.probe_array = probe;
           else
              app.probe_array(end+1) = probe;
           end

        end
        
        function bUnique = isunique_probe_id(~,probe_id)
            query = bids.Probes;
            result = fetch(query & ['probe_id LIKE "' probe_id '%"']);
            if isempty(result)
                bUnique = true;
            else
                bUnique = false;
                errordlg('This probe id is not unique, create a different one', 'Invalid Probe Id');
            end
        end
        
        function create_electrode_struct(app, num)
            electrode = struct();
            electrode.(app.electrode_flds{1}) = append('e_',  num2str(num, '%05d'));
            electrode.(app.electrode_flds{2}) = app.electrode_defaults{2};
            electrode.(app.electrode_flds{3}) = app.SubjectDropDown.Value; 
            for i = 4:numel(app.electrode_flds)
                electrode.(app.electrode_flds{i}) = app.electrode_defaults{i};
            end

            if isempty(app.electrode_array)
                app.electrode_array = electrode;
            else
                app.electrode_array(end+1) = electrode;
            end

        end
        
        function create_channel_struct(app, num)
            channel = struct();
            channel.(app.channel_flds{1}) = app.channel_defaults{1};
            channel.(app.channel_flds{2}) = num2str(num, '%05d');
            channel.(app.channel_flds{3}) = app.electrode_array(num+1).electrode_id;
            channel.(app.channel_flds{4}) = app.SubjectDropDown.Value;
            for i = 5:numel(app.channel_flds)
               channel.(app.channel_flds{i}) = app.channel_defaults{i};
            end
            % if ~isempty(app.ephys)
            %     channel.sampling_frequency = app.ephys.sampling_frequency;
            %     channel.sampling_frequency_unit = app.ephys.sampling_frequency_unit;
            %     channel.software_filters = app.ephys.software_filters;
            %     channel.hardware_filters = app.ephys.hardware_filters;
            % end

            if isempty(app.channel_array)
                app.channel_array = channel;
            else
                app.channel_array(end+1) = channel;
            end
        end
        
        % Probes have been edited or number increased -> change
        % electrode_array
        function updateElectrodesFromProbes(app)
            app.electrode_array = [];
            cnt = 0;            
            app.electrode_defaults{2} = app.probe_array(1).probe_id;
             for p = 1:length(app.probe_array)                
                nm = app.probe_array(p).contact_count;
                if nm < 1, nm = 1; end
                for j = 1:nm
                    
                    app.create_electrode_struct(cnt); 
                    cnt = cnt + 1;
                end
             end
             app.NumberofelectrodesEditField.Value = length(app.electrode_array);
             app.NumberofchannelsEF.Limits = [1 length(app.electrode_array)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global dbpar
            if isempty(dbpar)
                errordlg('Please initialize Datajoint: initDJ(lab)', 'Datajoint not up')
                return
            end
            app.lab_name = dbpar.Database;
            app.LabnameLabel.Text = app.lab_name;
            app.ProjectIds = fetch(eval([app.lab_name '.Projects']));
            app.ProjectDropDown.Items = {app.ProjectIds(:).projectid};

            project_id = app.ProjectIds(1).projectid;
            app.ProjectDropDown.Value = project_id;
            
            query = eval([app.lab_name '.Setups' ]);
            app.Setups = fetch(query, 'type');  % also fetch type to use to get details from bids ephys table
            app.SetupDropDown.Items = {app.Setups(:).setupid};
            app.SetupDropDown.Value = app.Setups(1).setupid;

            if strcmp(app.Setups(1).type, 'ephys')
                query = bids.Ephys;
                app.ephys = fetch(query & ['recording_setup="' app.Setups(idx).setupid '"'], '*');
            end

            query = eval([app.lab_name '.ProjectsSubjects']);
            app.SubjectIds = fetch(query & ['project="' project_id '"']);
            app.SubjectDropDown.Items = {app.SubjectIds(:).subject};
            app.SubjectDropDown.Value = app.SubjectIds(1).subject;

            %PROBES
            query = bids.Probes;
            app.probe_flds = {query.tableHeader.attributes(:).name};
            probe_fldtypes = {query.tableHeader.attributes(:).type};
            app.probe_defaults = fill_defaults(app, probe_fldtypes);

            app.ProbesUITable.ColumnName = app.probe_flds;
            editable = logical([0 0 ones(1, length(app.probe_flds)-2)]);
            app.ProbesUITable.ColumnEditable = editable;
            app.ProbesUITable.ColumnSortable = logical([1 zeros(1, length(app.probe_flds)-1)]);

            [~,guid] = fileparts(tempname);
            probe_id = guid(1:10);
            if isunique_probe_id(app,probe_id)
                app.probeGuid.Value = probe_id;
            end


            %Create a new Probe struct array
            app.create_probe_struct(0);  
            app.ProbesUITable.Data = transpose(struct2cell(app.probe_array));  

            %ELECTRODES
            query = bids.Electrodes;
            app.electrode_flds = {query.tableHeader.attributes(:).name};
            elec_fldtypes = {query.tableHeader.attributes(:).type};
            app.electrode_defaults = fill_defaults(app, elec_fldtypes);  
            app.electrode_defaults{2} = app.probe_array(1).probe_id;

            app.ElectrodesUITable.ColumnName = app.electrode_flds;
            app.ElectrodesUITable.ColumnFormat = cell(1,length(app.electrode_flds));
            app.ElectrodesUITable.ColumnFormat{2} = { app.probe_array(:).probe_id };

            editable = logical([0 1 0 ones(1, length(app.electrode_flds)-3)]);
            app.ElectrodesUITable.ColumnEditable = editable;
            app.ElectrodesUITable.ColumnSortable = logical([1 zeros(1, length(app.electrode_flds)-1)]);

            %Create a new Electrode struct array
            %HOW MANY CONTACTS ARE IN EACH PROBE -> MAKE CORRESPONDING NUMBER OF ELECTRODES
            app.create_electrode_struct(0);   
            app.ElectrodesUITable.Data = transpose(struct2cell(app.electrode_array)); 


            %CHANNELS
            query = bids.Channels;
            app.channel_flds = {query.tableHeader.attributes(:).name};
            chan_fldtypes = {query.tableHeader.attributes(:).type};
            app.channel_defaults = fill_defaults(app, chan_fldtypes); 

            app.ChannelsUITable.ColumnName = app.channel_flds;
            app.ChannelsUITable.ColumnFormat = cell(1,length(app.channel_flds));
            app.ChannelsUITable.ColumnFormat{3} = { app.electrode_array(:).electrode_id };
            
            editable = logical([1 0 1 0 ones(1, length(app.channel_flds)-4)]);
            app.ChannelsUITable.ColumnEditable = editable;
            app.ChannelsUITable.ColumnSortable = logical([0 1 1 zeros(1, length(app.channel_flds)-3)]);

           %Create a new Channel struct array
            app.create_channel_struct(0);              
            app.ChannelsUITable.Data = transpose(struct2cell(app.channel_array)); 

            %We need to limit the amount of channels that can be made
            app.NumberofchannelsEF.Limits = [1 length(app.electrode_array)];

            
            s = uistyle("BackgroundColor",[0.9 0.9 0.3]);
            app.ProbesUITable.addStyle(s,"row", 1);
            app.ElectrodesUITable.addStyle(s,"row", 1);
            app.ChannelsUITable.addStyle(s,"row", 1);

        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            project_id = app.ProjectDropDown.Value;

            query = eval([app.lab_name '.ProjectsSubjects']);
            app.SubjectIds = fetch(query & ['project="' project_id '"']);
            if isempty(app.SubjectIds)
                errordlg('No subject for this project!!!')
                return
            end

            app.SubjectDropDown.Items = {app.SubjectIds(:).subject};
            app.SubjectDropDown.Value = app.SubjectIds(1).subject;

            %are there Probes for this subject
            query = bids.Probes;
            probes = fetch(query & ['subject="' app.SubjectDropDown.Value '"'], '*');
            if isempty(probes)
                app.probe_array = []; %needs to be cleared because 
                % create_probe adds a probe to an existing array
                app.create_probe_struct(0); 
                app.NumberofprobesEditField.Value = 1;
                app.ProbesUITable.Data = transpose(struct2cell(app.probe_array));
            else
                app.NumberofprobesEditField.Value = length(probes);
                app.probe_array = probes;
                app.ProbesUITable.Data = transpose(struct2cell(app.probe_array(:)));
                
                app.electrode_defaults{2} = app.probe_array(1).probe_id;
                id = regexp(app.probe_array(1).probe_id, '_[0-9]+$', 'split');
                app.probeGuid.Value = id{1};

            end

            %are there Electrodes for this subject
            query = bids.Electrodes;
            electrodes = fetch(query & ['subject="' app.SubjectDropDown.Value '"'], '*');
            if isempty(electrodes)
                app.updateElectrodesFromProbes(); 
                app.ElectrodesUITable.Data = transpose(struct2cell(app.electrode_array(:)));
            else
                app.NumberofelectrodesEditField.Value = length(electrodes);
                app.electrode_array = electrodes;
                app.ElectrodesUITable.Data = transpose(struct2cell(app.electrode_array(:)));
            end

           query = bids.Channels;
           channels = fetch(query & ['subject="' app.SubjectDropDown.Value '"'], '*');
           app.NumberofchannelsEF.Limits = [1 length(app.electrode_array)];
           if isempty(channels)
                app.channel_array = [];
                app.create_channel_struct(0); 
                app.NumberofchannelsEF.Value = 1;
                app.ChannelsUITable.Data = transpose(struct2cell(app.channel_array));
           else
                app.NumberofchannelsEF.Value = length(channels);
                app.channel_array = channels;
                app.ChannelsUITable.Data = transpose(struct2cell(app.channel_array(:)));
           end
          
            app.ElectrodesUITable.ColumnFormat{2} = { app.probe_array(:).probe_id };
            app.ChannelsUITable.ColumnFormat{3} = { app.electrode_array(:).electrode_id };

            app.SAVEBtnProbe.Enable ="off";
            app.SAVEBtnElectrode.Enable ="off";
            app.SaveBtnChannel.Enable ="off";
        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)
            subject= app.SubjectDropDown.Value;
            query = bids.Probes;
            %are there Probes for this subject
            probes = fetch(query & ['subject="' subject '"'], '*');
            if isempty(probes)
                app.probe_array = [];
                app.create_probe_struct(0); 
                app.NumberofprobesEditField.Value = 1;
                app.ProbesUITable.Data = transpose(struct2cell(app.probe_array));
            else
                app.NumberofprobesEditField.Value = length(probes);
                app.probe_array = probes;
                app.ProbesUITable.Data = transpose(struct2cell(app.probe_array(:)));
            end

           query = bids.Electrodes;
           electrodes = fetch(query & ['subject="' subject '"'], '*');
           if isempty(electrodes)
                app.updateElectrodesFromProbes(); 
                app.ElectrodesUITable.Data = transpose(struct2cell(app.electrode_array(:)));
            else
                app.NumberofelectrodesEditField.Value = length(electrodes);
                app.electrode_array = electrodes;
                app.ElectrodesUITable.Data = transpose(struct2cell(app.electrode_array(:)));
           end

           query = bids.Channels;
           channels = fetch(query & ['subject="' subject '"'], '*');
           if length(app.electrode_array) < length(channels)
               app.NumberofchannelsEF.Limits = [1 length(channels)];
           else
                app.NumberofchannelsEF.Limits = [1 length(app.electrode_array)];
           end
           if isempty(channels)
                app.channel_array = [];
                app.create_channel_struct(0); 
                app.NumberofchannelsEF.Value = 1;
                app.ChannelsUITable.Data = transpose(struct2cell(app.channel_array));
           else
                app.NumberofchannelsEF.Value = length(channels);
                app.channel_array = channels;
                app.ChannelsUITable.Data = transpose(struct2cell(app.channel_array(:)));
           end

           app.ElectrodesUITable.ColumnFormat{2} = { app.probe_array(:).probe_id };
           app.ChannelsUITable.ColumnFormat{3} = { app.electrode_array(:).electrode_id };

           app.SAVEBtnProbe.Enable ="off";
           app.SAVEBtnElectrode.Enable ="off";
           app.SaveBtnChannel.Enable ="off";

        end

        % Value changed function: SetupDropDown
        function SetupDropDownValueChanged(app, event)
            value = app.SetupDropDown.Value;
            idx =  find(ismember({app.Setups(:).setupid}, value));
            
            if strcmp(app.Setups(idx).type, 'ephys')
                query = bids.Ephys;
                app.ephys = fetch(query & ['recording_setup="' app.Setups(idx).setupid '"'], '*');

                if ~isempty(app.ephys)
                    flds = fields(app.ephys);
                    for i = 1:length(flds)
                        ix = find(ismember(app.channel_flds, flds(i)),1);
                        if ~isempty(ix)
                            fld = app.channel_flds{ix};
                            app.channel_defaults{ix} = app.ephys.(fld);
                            for j = 1:length(app.channel_array)
                                app.channel_array(j).(fld) = app.ephys.(fld);
                            end
                        end
                    end
                end
                app.ChannelsUITable.Data = transpose(struct2cell(app.channel_array(:)));
            else
                warndlg('Not an ephys type setup')
            end
            
        end

        % Value changed function: probeGuid
        function probeGuidValueChanged(app, event)
            probe_id = app.probeGuid.Value;
            if ~isempty(probe_id) && isunique_probe_id(app, probe_id )
                probeids = { app.probe_array.probe_id };
                id = regexp(probeids{1}, '_[0-9]+$', 'split');
                probeids = strrep(probeids, id{1}, probe_id);
                if length(probeids) > 1
                    for i = 1:length(probeids) 
                        app.probe_array(i).probe_id = probeids{i};
                    end
                    app.ProbesUITable.Data = struct2table(app.probe_array);

                else
                    app.probe_array.probe_id = probeids{1};
                    app.ProbesUITable.Data = transpose(struct2cell(app.probe_array(:)));
                end

                %Also update probe_id in electrodes default
                %app.electrode_defaults{2} = app.probe_array(1).probe_id;
                %app.electrode_array(1).probe_id = app.probe_array(1).probe_id; 
                app.updateElectrodesFromProbes(); 
                app.ElectrodesUITable.Data = transpose(struct2cell(app.electrode_array(:)));
                app.ElectrodesUITable.ColumnFormat{2} = { app.probe_array(:).probe_id };

                app.SAVEBtnProbe.Enable ="on";
               % app.SAVEBtnElectrode.Enable ="on";
            end

        end

        % Value changed function: NumberofprobesEditField
        function NumberofprobesEditFieldValueChanged(app, event)
            value = app.NumberofprobesEditField.Value;
            num_probes = length(app.probe_array);
            diff = value - num_probes;
            if diff ~= 0
                if diff > 0
                    for i = 1:diff
                        create_probe_struct(app, num_probes + i -1)
                    end
                elseif diff < 0
                    app.probe_array = app.probe_array(1:value);
                end

                app.ProbesUITable.Data = transpose(struct2cell(app.probe_array(:)));

                app.ElectrodesUITable.ColumnFormat{2} = { app.probe_array(:).probe_id };

                app.updateElectrodesFromProbes();
                app.ElectrodesUITable.Data = transpose(struct2cell(app.electrode_array(:)));
            end

            app.SAVEBtnProbe.Enable ="on";
        end

        % Value changed function: NumberofelectrodesEditField
        function NumofelectrodesEFChanged(app, event)
            value = app.NumberofelectrodesEditField.Value;
            num_electrodes = length(app.electrode_array);
            diff = value - num_electrodes;
            if diff > 0
                for i = 1:diff
                    create_electrode_struct(app, num_electrodes + i -1)
                end
            elseif diff < 0
                app.electrode_array = app.electrode_array(1:value);
            end   
            app.ElectrodesUITable.Data = transpose(struct2cell(app.electrode_array(:)));
            app.NumberofchannelsEF.Limits = [1 length(app.electrode_array)];

            app.ChannelsUITable.ColumnFormat{3} = { app.electrode_array(:).electrode_id };

            app.SAVEBtnElectrode.Enable ="on";
        end

        % Value changed function: NumberofchannelsEF
        function NumberofchannelsEFValueChanged(app, event)
            value = app.NumberofchannelsEF.Value;
                        
            num_channels = length(app.channel_array);
            diff = value - num_channels;
            if diff > 0
                for i = 1:diff
                    create_channel_struct(app, num_channels + i -1)
                end
            elseif diff < 0
                app.channel_array = app.channel_array(1:value);
            end   
            app.ChannelsUITable.Data = transpose(struct2cell(app.channel_array(:)));

            app.SaveBtnChannel.Enable ="on";
        end

        % Cell edit callback: ProbesUITable
        function ProbesUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            field = app.probe_flds{indices(2)};
            app.probe_array(indices(1)).(field) = newData;

            if indices(1) == 1
                app.probe_defaults{indices(2)} = newData;
            end

            app.updateElectrodesFromProbes();
            app.ElectrodesUITable.Data = transpose(struct2cell(app.electrode_array(:)));

            app.SAVEBtnProbe.Enable ="on";
            
        end

        % Cell edit callback: ElectrodesUITable
        function ElectrodesUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            field = app.electrode_flds{indices(2)};
            app.electrode_array(indices(1)).(field) = newData;

            if indices(1) == 1
                app.electrode_defaults{indices(2)} = newData;
            end

            app.SAVEBtnElectrode.Enable ="on";
            
        end

        % Cell edit callback: ChannelsUITable
        function ChannelsUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            field = app.channel_flds{indices(2)};
            app.channel_array(indices(1)).(field) = newData;

            if indices(1) == 1
                app.channel_defaults{indices(2)} = newData;
            end

            app.SaveBtnChannel.Enable ="on";

        end

        % Button pushed function: SAVEBtnProbe
        function SAVEButton_Probes(app, event)
            del(bids.Probes & ['subject="' app.SubjectDropDown.Value '"'])
            insert(bids.Probes, app.probe_array)
        end

        % Button pushed function: SAVEBtnElectrode
        function SAVEButton_Electrodes(app, event)
            del(bids.Electrodes & ['subject="' app.SubjectDropDown.Value '"'])
            insert(bids.Electrodes, app.electrode_array)    
        end

        % Button pushed function: SaveBtnChannel
        function SAVEBtn_Channel(app, event)
            del(bids.Channels & ['subject="' app.SubjectDropDown.Value '"'])
            insert(bids.Channels, app.channel_array) 
        end

        % Button pushed function: ImportButton
        function ImportBtnPushed(app, event)
            [fn, fp] = uigetfile('*.xlsx');
            ChanTbl = readtable(fullfile(fp, fn));
            columns = ChanTbl.Properties.VariableNames;
            flds = app.channel_flds;
            correspond = contains(flds, columns);
            if sum(correspond) == length(flds)
                app.ChannelsUITable.Data = table2cell(ChanTbl);
                app.SaveBtnChannel.Enable ="on";
            else
                missing = find(correspond == 0);
                colstr = '';
                for i=1:length(missing)
                    colstr = append(colstr, flds{missing(i)}, ', ');
                end
                warndlg(colstr, 'Missing columns');
            end

        end

        % Button pushed function: ExportButton
        function ExportBtnPushed(app, event)
            selpath = uigetdir();
            ChanTbl = struct2table(app.channel_array);
            filename = fullfile(selpath, 'Channels.xlsx');
            writetable(ChanTbl, filename)
        end

        % Button pushed function: SaveButton
        function bpSaveDefaultProbe(app, event)
            default = app.probe_array(1);
            if ~isempty(default)
                defaultProbe = struct();
                %defaultProbe.name = append(default.probe_id);
                defaultProbe.manufacturer = default.manufacturer;
                defaultProbe.probe_type = default.probe_type;
                defaultProbe.material = default.material;
                defaultProbe.width = default.width;
                defaultProbe.height = default.height;
                defaultProbe.depth = default.depth;
                defaultProbe.dimension_unit = default.dimension_unit;
                defaultProbe.contact_count = default.contact_count;
    
                [fn,loc] = uiputfile('default_probe.mat');
                save(fullfile(loc, fn), 'defaultProbe')
            end

        %    insert(bids.ProbeDefaults, defaultProbe);
        end

        % Button pushed function: RetrieveButton
        function ProbedefaultsClicked(app, event)
            [fn,loc] = uigetfile('default_probe.mat');
            if fn ~= 0
                dP = load(fullfile(loc, fn), 'defaultProbe');
                if isfield(dP, 'defaultProbe')
                    defaultProbe = dP.defaultProbe;
                    members = fields(defaultProbe);
                    for i = 1:length(members)
                        idx = find(ismember(app.probe_flds, members(i)),1);
                        if ~isempty(idx)
                            app.probe_defaults{idx} = defaultProbe.(app.probe_flds{idx});
                        end
                    end

                    app.probe_array = [];
                    app.create_probe_struct(0); 
                    app.NumberofprobesEditField.Value = 1;
                    app.ProbesUITable.Data = transpose(struct2cell(app.probe_array));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProjectDropDownLabel = uilabel(app.UIFigure);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDown.Items = {'Option'};
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.ProjectDropDown.Value = 'Option';
            app.LabnameLabel = uilabel(app.UIFigure);
            app.LabnameLabel.Text = 'Labname';
            app.SetupDropDownLabel = uilabel(app.UIFigure);
            app.SetupDropDownLabel.Text = 'Setup';
            app.SetupDropDown = uidropdown(app.UIFigure);
            app.SetupDropDown.Items = {'Option'};
            app.SetupDropDown.ValueChangedFcn = createCallbackFcn(app, @SetupDropDownValueChanged, true);
            app.SetupDropDown.Value = 'Option';
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDown.Items = {'Option'};
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SubjectDropDown.Value = 'Option';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ProbesElcectrodesChannels.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProbesTab = uitab(app.TabGroup);
            app.ProbesTab.Title = 'Probes';
            app.ProbesUITable = uitable(app.ProbesTab);
            app.ProbesUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ProbesUITable.RowName = {};
            app.ProbesUITable.CellEditCallback = createCallbackFcn(app, @ProbesUITableCellEdit, true);
            app.probeUIDauniqueidentifierLabel = uilabel(app.ProbesTab);
            app.probeUIDauniqueidentifierLabel.Text = {'probe UID'; '(a unique identifier)'};
            app.probeGuid = uieditfield(app.ProbesTab, 'text');
            app.probeGuid.ValueChangedFcn = createCallbackFcn(app, @probeGuidValueChanged, true);
            app.NumberofprobesEditFieldLabel = uilabel(app.ProbesTab);
            app.NumberofprobesEditFieldLabel.Text = 'Number of probes';
            app.NumberofprobesEditField = uieditfield(app.ProbesTab, 'numeric');
            app.NumberofprobesEditField.Limits = [1 Inf];
            app.NumberofprobesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofprobesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofprobesEditFieldValueChanged, true);
            app.NumberofprobesEditField.Value = 1;
            app.SAVEBtnProbe = uibutton(app.ProbesTab, 'push');
            app.SAVEBtnProbe.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_Probes, true);
            app.SAVEBtnProbe.Enable = 'off';
            app.SAVEBtnProbe.Text = 'SAVE';
            app.SaveButton = uibutton(app.ProbesTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @bpSaveDefaultProbe, true);
            app.SaveButton.Text = 'Save';
            app.RetrieveButton = uibutton(app.ProbesTab, 'push');
            app.RetrieveButton.ButtonPushedFcn = createCallbackFcn(app, @ProbedefaultsClicked, true);
            app.RetrieveButton.Text = 'Retrieve';
            app.DEFAULTPROBELabel = uilabel(app.ProbesTab);
            app.DEFAULTPROBELabel.Text = 'DEFAULT PROBE';
            app.ElectrodesTab = uitab(app.TabGroup);
            app.ElectrodesTab.Title = 'Electrodes';
            app.ElectrodesUITable = uitable(app.ElectrodesTab);
            app.ElectrodesUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ElectrodesUITable.RowName = {};
            app.ElectrodesUITable.CellEditCallback = createCallbackFcn(app, @ElectrodesUITableCellEdit, true);
            app.NumberofelectrodesEditFieldLabel = uilabel(app.ElectrodesTab);
            app.NumberofelectrodesEditFieldLabel.Text = 'Number of electrodes';
            app.NumberofelectrodesEditField = uieditfield(app.ElectrodesTab, 'numeric');
            app.NumberofelectrodesEditField.Limits = [1 Inf];
            app.NumberofelectrodesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofelectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumofelectrodesEFChanged, true);
            app.NumberofelectrodesEditField.Value = 1;
            app.SAVEBtnElectrode = uibutton(app.ElectrodesTab, 'push');
            app.SAVEBtnElectrode.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_Electrodes, true);
            app.SAVEBtnElectrode.Enable = 'off';
            app.SAVEBtnElectrode.Text = 'SAVE';
            app.DEFAULTSLabel = uilabel(app.ElectrodesTab);
            app.DEFAULTSLabel.Text = 'DEFAULTS';
            app.ChannelsTab = uitab(app.TabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.NumberofchannelsEditFieldLabel = uilabel(app.ChannelsTab);
            app.NumberofchannelsEditFieldLabel.Text = 'Number of channels';
            app.NumberofchannelsEF = uieditfield(app.ChannelsTab, 'numeric');
            app.NumberofchannelsEF.Limits = [1 Inf];
            app.NumberofchannelsEF.ValueDisplayFormat = '%.0f';
            app.NumberofchannelsEF.ValueChangedFcn = createCallbackFcn(app, @NumberofchannelsEFValueChanged, true);
            app.NumberofchannelsEF.Value = 1;
            app.SaveBtnChannel = uibutton(app.ChannelsTab, 'push');
            app.SaveBtnChannel.ButtonPushedFcn = createCallbackFcn(app, @SAVEBtn_Channel, true);
            app.SaveBtnChannel.Enable = 'off';
            app.SaveBtnChannel.Text = 'SAVE';
            app.ChannelsUITable = uitable(app.ChannelsTab);
            app.ChannelsUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ChannelsUITable.RowName = {};
            app.ChannelsUITable.CellEditCallback = createCallbackFcn(app, @ChannelsUITableCellEdit, true);
            app.ExportButton = uibutton(app.ChannelsTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportBtnPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.ChannelsTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportBtnPushed, true);
            app.ImportButton.Text = 'Import';
            app.DEFAULTSLabel_2 = uilabel(app.ChannelsTab);
            app.DEFAULTSLabel_2.Text = 'DEFAULTS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prelch

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end